classdef db_ethernet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        db_ethernetUIFigure  matlab.ui.Figure
        controltablePanel    matlab.ui.container.Panel
        InputTextArea        matlab.ui.control.TextArea
        FormatDropDown       matlab.ui.control.DropDown
        SendButton           matlab.ui.control.Button
        ConnectButton        matlab.ui.control.Button
        displaytablePanel    matlab.ui.container.Panel
        DisplayTextArea      matlab.ui.control.TextArea
        SaveButton           matlab.ui.control.Button
        ClearButton          matlab.ui.control.Button
    end


    properties (Access = private)
        net_buffer   = 65536
        dis_data     = 'App initializing...\n'
        tcpipClient  = tcpip('192.168.1.10', 7, 'NetworkRole', 'Client');
        clientrddata = 0;
        socket_timer = timer;
    end

    methods (Access = public)

        function client_rectask(~, ~, app)
            if app.tcpipClient.BytesAvailable > 0
                app.clientrddata = fread(app.tcpipClient, app.tcpipClient.BytesAvailable, 'char');
                DisplayTextAreaValueChanged(app, sprintf('Received: %s', app.clientrddata));
            end
        end

        function client_errtask(~, ~, app)
            DisplayTextAreaValueChanged(app, sprintf('Error when timer of socket executing.\n'));
        end

    end


    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            set(app.tcpipClient,'InputBufferSize',app.net_buffer);
            set(app.tcpipClient,'OutputBufferSize',app.net_buffer);
            set(app.tcpipClient,'Timeout',30);
%           fopen(app.tcpipClient);

            % Display the client net connect information
            DisplayTextAreaValueChanged(app, sprintf('The client connect with server as 192.168.1.10.\n'));

            % Configure and start the socket task timer
            set(app.socket_timer, 'Name', 'client_timer');
            set(app.socket_timer, 'TimerFcn', @(~, ~, app)client_rectask);
            set(app.socket_timer, 'ErrorFcn', @(~, ~, app)client_errtask);
            set(app.socket_timer, 'Period', 1);
            set(app.socket_timer, 'TasksToExecute', 24*60*60);
            set(app.socket_timer, 'ExecutionMode', 'fixedRate');

            start(app.socket_timer);
        end

        % Value changed function: DisplayTextArea
        function DisplayTextAreaValueChanged(app, event)
            app.dis_data = strcat(app.dis_data, event, '\n');
            % Use the sprintf display the '\n'.
            app.DisplayTextArea.Value = sprintf(app.dis_data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.db_ethernetUIFigure = uifigure;
            app.db_ethernetUIFigure.Name = 'db_ethernet';
            app.controltablePanel = uipanel(app.db_ethernetUIFigure);
            app.controltablePanel.Title = 'control table';
            app.InputTextArea = uitextarea(app.controltablePanel);
            app.FormatDropDown = uidropdown(app.controltablePanel);
            app.FormatDropDown.Items = {'char', 'hex', 'dec'};
            app.FormatDropDown.Value = 'char';
            app.SendButton = uibutton(app.controltablePanel, 'push');
            app.SendButton.Text = 'SendButton';
            app.ConnectButton = uibutton(app.controltablePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'ConnectButton';
            app.displaytablePanel = uipanel(app.db_ethernetUIFigure);
            app.displaytablePanel.Title = 'display table';
            app.DisplayTextArea = uitextarea(app.displaytablePanel);
            app.DisplayTextArea.ValueChangedFcn = createCallbackFcn(app, @DisplayTextAreaValueChanged, true);
            app.DisplayTextArea.Value = {'App initializing...'};
            app.SaveButton = uibutton(app.displaytablePanel, 'push');
            app.SaveButton.Text = 'SaveButton';
            app.ClearButton = uibutton(app.displaytablePanel, 'push');
            app.ClearButton.Text = 'ClearButton';
        end
    end

    methods (Access = public)

        % Construct app
        function app = db_ethernet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.db_ethernetUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.db_ethernetUIFigure)
        end
    end
end